classdef AGquadGUI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		GaussGUI                    matlab.ui.Figure
		Panel2D                     matlab.ui.container.Panel
		nodesexact2D                matlab.ui.container.Panel
		nodes2dexact                matlab.ui.control.NumericEditField
		n1Label                     matlab.ui.control.Label
		FunctiontointegratePanel_2  matlab.ui.container.Panel
		FunctionEdit2D              matlab.ui.control.EditField
		FunctionEditField_2Label    matlab.ui.control.Label
		ExampleList2D               matlab.ui.control.ListBox
		ExampleLabel_2              matlab.ui.control.Label
		Numberofnodes2D             matlab.ui.container.Panel
		nodes2dy                    matlab.ui.control.NumericEditField
		n_2EditFieldLabel           matlab.ui.control.Label
		nodes2dx                    matlab.ui.control.NumericEditField
		n_1Label                    matlab.ui.control.Label
		Jacobiweightexponents2D     matlab.ui.container.Panel
		beta2_2D                    matlab.ui.control.NumericEditField
		beta_2EditFieldLabel        matlab.ui.control.Label
		alpha2_2D                   matlab.ui.control.NumericEditField
		alpha_2EditFieldLabel       matlab.ui.control.Label
		beta1_2D                    matlab.ui.control.NumericEditField
		beta_1EditFieldLabel        matlab.ui.control.Label
		alpha1_2D                   matlab.ui.control.NumericEditField
		alpha_1EditFieldLabel       matlab.ui.control.Label
		Panel1D                     matlab.ui.container.Panel
		nodesexact1D                matlab.ui.container.Panel
		nodes1dexact                matlab.ui.control.NumericEditField
		nEditFieldLabel_2           matlab.ui.control.Label
		FunctiontointegratePanel    matlab.ui.container.Panel
		FunctionEdit1D              matlab.ui.control.EditField
		FunctionEditFieldLabel      matlab.ui.control.Label
		ExampleList1D               matlab.ui.control.ListBox
		ExampleLabel                matlab.ui.control.Label
		Numberofnodes1D             matlab.ui.container.Panel
		nodes1d                     matlab.ui.control.NumericEditField
		nEditFieldLabel             matlab.ui.control.Label
		Jacobiweightexponents1D     matlab.ui.container.Panel
		beta_1D                     matlab.ui.control.NumericEditField
		betaEditFieldLabel          matlab.ui.control.Label
		alpha_1D                    matlab.ui.control.NumericEditField
		alphaEditFieldLabel         matlab.ui.control.Label
		Integrandfunction           matlab.ui.container.ButtonGroup
		userfunction                matlab.ui.control.RadioButton
		example                     matlab.ui.control.RadioButton
		Information                 matlab.ui.control.Button
		Stop                        matlab.ui.control.Button
		Run                         matlab.ui.control.Button
		Kindofintegral              matlab.ui.container.ButtonGroup
		case2d                      matlab.ui.control.RadioButton
		case1d                      matlab.ui.control.RadioButton
	end


    properties (Access = private)
        ext_figure
        area
        devices
        question
        rngstatus = rng;
        DATA
        RESULTS
    end

    properties (Access = public)
        stop = 0;
    end

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
            show = 10;
            ss0 = get(0,'screensize');
            figure(show), clf
            if ispc
                set(gcf,'NumberTitle','off','Position',[1,ss0(4)-520,500,350])
            else
                set(gcf,'NumberTitle','off','Position',[1,ss0(4)-350,500,350])
            end
            % figure(1), clf
            % vv = get(gcf,'position');
            % set(gcf,'NumberTitle','off','Position',[ss0(3)-vv(3),1,vv(3:4)])
           
		end

		% Button pushed function: Run
		function RunPushed(app, event)

            % if (app.Datatobeinverted.SelectedObject == app.RealData) && isempty(app.DataFile.Value)
            %     uialert(app.GaussGUI, 'You must select a file', 'Warning', 'Icon', 'Error')
            %     return
            % end

            if isempty(app.ext_figure) || ~isvalid(app.ext_figure)
                ss0 = get(0,'screensize');
                if ispc
                    posresults = [ss0(3)-640 ss0(4)-430 640 400];
                else
                    posresults = [ss0(3)-640 ss0(4)-400 640 400];
                end
                app.ext_figure = uifigure('Name', 'Results',...
                    'Position',posresults,...
                    'Resize', 'on',...
                    'Color', [0.9412 0.9412 0.9412]);

                app.area = uitextarea(app.ext_figure,...
                    'Position', [4,4,634,392],...
                    'FontSize', 18,...
                    'FontName', 'LM Mono 8',...
                    'FontColor', [0.149 0.149 0.149],...
                    'BackgroundColor', [0.9412 0.9412 0.9412],...
                    'Editable', 'on');
            end

            switch app.Kindofintegral.SelectedObject
                case app.case1d
                    n = app.nodes1d.Value;
                    nexact = app.nodes1dexact.Value;
                    alpha = app.alpha_1D.Value;
                    beta = app.beta_1D.Value;
                    if app.Integrandfunction.SelectedObject == app.example
                            switch app.ExampleList1D.Value
                                case 'Example 1'
                                    examp = 1;
                                case 'Example 2'
                                    examp = 2;
                                case 'Example 3'
                                    examp = 3;
								case 'Example 4'
									examp = 4;
                            end
                    else
                        examp = app.FunctionEdit1D.Value;
                        examp = string(examp);
                    end

                    calledbyapp = 1;
                    textarea = app.area;
                    app.stop = 0;

                    approxint1D

                case app.case2d
                    m = app.nodes2dx.Value;
                    n = app.nodes2dy.Value;
                    nexact = app.nodes2dexact.Value;
                    alpha1 = app.alpha1_2D.Value;
                    beta1 = app.beta1_2D.Value;
                    alpha2 = app.alpha2_2D.Value;
                    beta2 = app.beta2_2D.Value;
                    if app.Integrandfunction.SelectedObject == app.example
                        switch app.ExampleList2D.Value
                            case 'Example 1'
                                examp = 1;
                            case 'Example 2'
                                examp = 2;
							case 'Example 3'
								examp = 3;
                        end
                    else
                        examp = app.FunctionEdit2D.Value;
                        examp = string(examp);
                    end             

                    calledbyapp = 1;
                    textarea = app.area;
                    app.stop = 0;

                    approxint2D
            end


		end

		% Button pushed function: Stop
		function StopButtonPushed(app, event)
            app.stop = 1;
		end

		% Selection changed function: Kindofintegral
		function KindofintegralSelectionChanged(app, event)
            value = app.Kindofintegral.SelectedObject;

            switch app.Integrandfunction.SelectedObject
                case app.example
                    if value == app.case1d
                        app.Panel2D.Enable = 'off';
                        app.Panel1D.Enable = 'on';
                        app.FunctionEdit1D.Enable = "off";
                        app.FunctionEditFieldLabel.Enable = 'off';
                        app.ExampleList1D.Enable = "on";
                        app.ExampleLabel.Enable = "on";
                    else
                        app.Panel2D.Enable = 'on';
                        app.Panel1D.Enable = 'off';
                        app.FunctionEdit2D.Enable = "off";
                        app.FunctionEditField_2Label.Enable = 'off';
                        app.ExampleList2D.Enable = "on";
                        app.ExampleLabel_2.Enable = "on";
                    end
                case app.userfunction
                    if value == app.case1d
                        app.Panel2D.Enable = 'off';
                        app.Panel1D.Enable = 'on';
                        app.FunctionEdit1D.Enable = "on";
                        app.FunctionEditFieldLabel.Enable = 'on';
                        app.ExampleList1D.Enable = "off";
                        app.ExampleLabel.Enable = "off";
                     else
                        app.Panel2D.Enable = 'on';
                        app.Panel1D.Enable = 'off';
                        app.FunctionEdit2D.Enable = "on";
                        app.FunctionEditField_2Label.Enable = 'on';
                        app.ExampleList2D.Enable = "off";
                        app.ExampleLabel_2.Enable = "off";
                     end
            end


		end

		% Button pushed function: Information
		function InformationButtonPushed(app, event)
            isunix;
			if isunix
				system('evince information.pdf &');
			else
				open('information.pdf');
			end
		end

		% Selection changed function: Integrandfunction
		function IntegrandfunctionSelectionChanged(app, event)
            value = app.Integrandfunction.SelectedObject;
            
            switch app.Kindofintegral.SelectedObject
                case app.case1d
                    if value == app.example
                        app.FunctionEdit1D.Enable = "off";
                        app.FunctionEditFieldLabel.Enable = 'off';
                        app.ExampleList1D.Enable = "on";
                        app.ExampleLabel.Enable = "on";
                    else 
                        app.FunctionEdit1D.Enable = "on";
                        app.FunctionEditFieldLabel.Enable = 'on';
                        app.ExampleList1D.Enable = "off";
                        app.ExampleLabel.Enable = "off";
                    end
                case app.case2d
                     if value == app.example
                        app.FunctionEdit2D.Enable = "off";
                        app.FunctionEditField_2Label.Enable = 'off';
                        app.ExampleList2D.Enable = "on";
                        app.ExampleLabel_2.Enable = "on";
                    else 
                        app.FunctionEdit2D.Enable = "on";
                        app.FunctionEditField_2Label.Enable = 'on';
                        app.ExampleList2D.Enable = "off";
                        app.ExampleLabel_2.Enable = "off";
                    end
            end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.GaussGUI = uifigure('Visible', 'off');
			app.GaussGUI.Name = 'AGquadGUI';
			app.Kindofintegral = uibuttongroup(app.GaussGUI);
			app.Kindofintegral.SelectionChangedFcn = createCallbackFcn(app, @KindofintegralSelectionChanged, true);
			app.Kindofintegral.Title = 'Kind of integral';
			app.case1d = uiradiobutton(app.Kindofintegral);
			app.case1d.Text = '1D case';
			app.case1d.Value = true;
			app.case2d = uiradiobutton(app.Kindofintegral);
			app.case2d.Text = '2D case';
			app.Run = uibutton(app.GaussGUI, 'push');
			app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunPushed, true);
			app.Run.Text = 'Run';
			app.Stop = uibutton(app.GaussGUI, 'push');
			app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
			app.Stop.Text = 'Stop';
			app.Information = uibutton(app.GaussGUI, 'push');
			app.Information.ButtonPushedFcn = createCallbackFcn(app, @InformationButtonPushed, true);
			app.Information.Text = 'Information about test functions';
			app.Integrandfunction = uibuttongroup(app.GaussGUI);
			app.Integrandfunction.SelectionChangedFcn = createCallbackFcn(app, @IntegrandfunctionSelectionChanged, true);
			app.Integrandfunction.Title = 'Integrand function';
			app.example = uiradiobutton(app.Integrandfunction);
			app.example.Text = 'Example';
			app.example.Value = true;
			app.userfunction = uiradiobutton(app.Integrandfunction);
			app.userfunction.Text = 'User function';
			app.Panel1D = uipanel(app.GaussGUI);
			app.Panel1D.Title = '1D case';
			app.Jacobiweightexponents1D = uipanel(app.Panel1D);
			app.Jacobiweightexponents1D.Title = 'Jacobi weight exponents';
			app.alphaEditFieldLabel = uilabel(app.Jacobiweightexponents1D);
			app.alphaEditFieldLabel.Interpreter = 'latex';
			app.alphaEditFieldLabel.Text = '\alpha';
			app.alpha_1D = uieditfield(app.Jacobiweightexponents1D, 'numeric');
			app.betaEditFieldLabel = uilabel(app.Jacobiweightexponents1D);
			app.betaEditFieldLabel.Interpreter = 'latex';
			app.betaEditFieldLabel.Text = '\beta';
			app.beta_1D = uieditfield(app.Jacobiweightexponents1D, 'numeric');
			app.Numberofnodes1D = uipanel(app.Panel1D);
			app.Numberofnodes1D.Title = 'Number of nodes';
			app.nEditFieldLabel = uilabel(app.Numberofnodes1D);
			app.nEditFieldLabel.Interpreter = 'tex';
			app.nEditFieldLabel.Text = 'n';
			app.nodes1d = uieditfield(app.Numberofnodes1D, 'numeric');
			app.nodes1d.Limits = [0 Inf];
			app.nodes1d.Value = 2;
			app.FunctiontointegratePanel = uipanel(app.Panel1D);
			app.FunctiontointegratePanel.Title = 'Function to integrate';
			app.ExampleLabel = uilabel(app.FunctiontointegratePanel);
			app.ExampleLabel.Text = 'Example';
			app.ExampleList1D = uilistbox(app.FunctiontointegratePanel);
			app.ExampleList1D.Items = {'Example 1', 'Example 2', 'Example 3', 'Example 4'};
			app.ExampleList1D.Value = 'Example 2';
			app.FunctionEditFieldLabel = uilabel(app.FunctiontointegratePanel);
			app.FunctionEditFieldLabel.Enable = 'off';
			app.FunctionEditFieldLabel.Text = 'Function';
			app.FunctionEdit1D = uieditfield(app.FunctiontointegratePanel, 'text');
			app.FunctionEdit1D.Enable = 'off';
			app.FunctionEdit1D.Value = 'exp(x)';
			app.nodesexact1D = uipanel(app.Panel1D);
			app.nodesexact1D.Title = 'Number of nodes for the exact solution';
			app.nEditFieldLabel_2 = uilabel(app.nodesexact1D);
			app.nEditFieldLabel_2.Interpreter = 'tex';
			app.nEditFieldLabel_2.Text = 'n';
			app.nodes1dexact = uieditfield(app.nodesexact1D, 'numeric');
			app.nodes1dexact.Limits = [0 Inf];
			app.nodes1dexact.Value = 512;
			app.Panel2D = uipanel(app.GaussGUI);
			app.Panel2D.Enable = 'off';
			app.Panel2D.Title = '2D case';
			app.Jacobiweightexponents2D = uipanel(app.Panel2D);
			app.Jacobiweightexponents2D.Title = 'Jacobi weight exponents';
			app.alpha_1EditFieldLabel = uilabel(app.Jacobiweightexponents2D);
			app.alpha_1EditFieldLabel.Interpreter = 'tex';
			app.alpha_1EditFieldLabel.Text = '\alpha_1';
			app.alpha1_2D = uieditfield(app.Jacobiweightexponents2D, 'numeric');
			app.beta_1EditFieldLabel = uilabel(app.Jacobiweightexponents2D);
			app.beta_1EditFieldLabel.Interpreter = 'tex';
			app.beta_1EditFieldLabel.Text = '\beta_1';
			app.beta1_2D = uieditfield(app.Jacobiweightexponents2D, 'numeric');
			app.alpha_2EditFieldLabel = uilabel(app.Jacobiweightexponents2D);
			app.alpha_2EditFieldLabel.Interpreter = 'tex';
			app.alpha_2EditFieldLabel.Text = '\alpha_2';
			app.alpha2_2D = uieditfield(app.Jacobiweightexponents2D, 'numeric');
			app.beta_2EditFieldLabel = uilabel(app.Jacobiweightexponents2D);
			app.beta_2EditFieldLabel.Interpreter = 'tex';
			app.beta_2EditFieldLabel.Text = '\beta_2';
			app.beta2_2D = uieditfield(app.Jacobiweightexponents2D, 'numeric');
			app.Numberofnodes2D = uipanel(app.Panel2D);
			app.Numberofnodes2D.Title = 'Number of nodes';
			app.n_1Label = uilabel(app.Numberofnodes2D);
			app.n_1Label.Interpreter = 'tex';
			app.n_1Label.Text = 'n_1';
			app.nodes2dx = uieditfield(app.Numberofnodes2D, 'numeric');
			app.nodes2dx.Limits = [0 Inf];
			app.nodes2dx.Value = 2;
			app.n_2EditFieldLabel = uilabel(app.Numberofnodes2D);
			app.n_2EditFieldLabel.Interpreter = 'tex';
			app.n_2EditFieldLabel.Text = 'n_2';
			app.nodes2dy = uieditfield(app.Numberofnodes2D, 'numeric');
			app.nodes2dy.Limits = [0 Inf];
			app.nodes2dy.Value = 2;
			app.FunctiontointegratePanel_2 = uipanel(app.Panel2D);
			app.FunctiontointegratePanel_2.Title = 'Function to integrate';
			app.ExampleLabel_2 = uilabel(app.FunctiontointegratePanel_2);
			app.ExampleLabel_2.Text = 'Example';
			app.ExampleList2D = uilistbox(app.FunctiontointegratePanel_2);
			app.ExampleList2D.Items = {'Example 1', 'Example 2', 'Example 3'};
			app.ExampleList2D.Value = 'Example 2';
			app.FunctionEditField_2Label = uilabel(app.FunctiontointegratePanel_2);
			app.FunctionEditField_2Label.Text = 'Function';
			app.FunctionEdit2D = uieditfield(app.FunctiontointegratePanel_2, 'text');
			app.FunctionEdit2D.Value = 'exp(x+y)';
			app.nodesexact2D = uipanel(app.Panel2D);
			app.nodesexact2D.Title = 'Number of nodes for the exact solution';
			app.n1Label = uilabel(app.nodesexact2D);
			app.n1Label.Interpreter = 'tex';
			app.n1Label.Text = 'n_1 = n_2';
			app.nodes2dexact = uieditfield(app.nodesexact2D, 'numeric');
			app.nodes2dexact.Limits = [0 Inf];
			app.nodes2dexact.Value = 512;
			% Show the figure after all components are created
			app.GaussGUI.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = AGquadGUI

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.GaussGUI)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.GaussGUI)
		end
	end
end